classdef ObliqueShock_Applet < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        UpstreamMachNumberM1Slider      matlab.ui.control.Slider
        UpstreamMachNumberM1SliderLabel  matlab.ui.control.Label
        ResetButton                     matlab.ui.control.Button
        HighlightFlowRegimesCheckBox_2  matlab.ui.control.CheckBox
        HighlightRegionofInfluenceCheckBox  matlab.ui.control.CheckBox
        OptionsLabel                    matlab.ui.control.Label
        StrongSolutionCheckBox          matlab.ui.control.CheckBox
        FlowDeflectionAngleEditField    matlab.ui.control.NumericEditField
        FlowDeflectionAngleEditFieldLabel  matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        M1 = 0; 
        flowDeflectionAngle = 0; 
        gamma = 1.4; 
        strongSolution = 0; 
        highRegionSilenceInfluence = 0; 
        highFlowRegimes = 0; 
        xCoordinates = [-0.25 0.25 0.25 -0.25];
        yCoordinates = [0 -0.25 0.25 0];
        xShockInput = -0.25:0.001:1.5;
        shockAngle = 0; 
        maxShockAngle = 0; 
        maxDeflectionAngle = 0;
    end
    
    methods (Access = public)


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = 'ObliqueShockSim (v1.0)';
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            cla(app.UIAxes);
            app.M1 = 2; % Reset to Mach 2 
            app.UpstreamMachNumberM1Slider.Value = 2; 
            app.flowDeflectionAngle = 20*pi/180; % Reset to 20deg deflection 
            app.FlowDeflectionAngleEditField.Value = 20; 
            app.strongSolution = 0; % Weak Solution 
            app.StrongSolutionCheckBox.Value = 0; 
            app.highRegionSilenceInfluence = 0; 
            app.HighlightRegionofInfluenceCheckBox.Value = 0;
            app.highFlowRegimes = 0; 
            app.HighlightFlowRegimesCheckBox_2.Value = 0;
            app.yCoordinates = [0 -0.5*tand(app.flowDeflectionAngle*180/pi) 0.5*tand(app.flowDeflectionAngle*180/pi) 0];
            lambda = (((app.M1)^2 - 1)^2 - 3*(1 + (app.gamma - 1)/2*(app.M1)^2)...
                *(1 + (app.gamma + 1)/2*(app.M1)^2)*(tan(app.flowDeflectionAngle))^2)^(1/2); 
            chi = (((app.M1)^2 - 1)^3 - 9*(1 + (app.gamma - 1)/2*(app.M1)^2)...
                    *(1 + (app.gamma - 1)/2*(app.M1)^2 + (app.gamma + 1)/4*(app.M1)^4)...
                    *(tan(app.flowDeflectionAngle))^2)/lambda^3;
            app.shockAngle = atan(((app.M1)^2 - 1 + 2*lambda*cos((4*pi*(app.strongSolution==0) + acos(chi))/3))...
                /(3*tan(app.flowDeflectionAngle)*(1 + (app.gamma - 1)/2*(app.M1)^2)));
            app.maxShockAngle = 1/2*acos(((app.M1)^2*(app.gamma-1)+4 ...
                - sqrt(16*(app.gamma + 1) + 8*(app.M1)^2*((app.gamma)^2 - 1) + (app.M1)^4*(app.gamma + 1)^2))...
                    /(2*app.gamma*(app.M1)^2));
            app.maxDeflectionAngle = atan((2*((app.M1)^2*(sin(app.maxShockAngle))^2 - 1))...
                                    /(tan(app.maxShockAngle)*(2 + (app.M1)^2*(app.gamma + cos(2*app.maxShockAngle)))));
            hold(app.UIAxes,'on');
            h1 = plot(app.UIAxes,app.xCoordinates,app.yCoordinates,'linewidth',2,'color','black');
                shockQuality = length(app.xShockInput);
            h2 = plot(app.UIAxes,app.xShockInput,tan(app.shockAngle)*(app.xShockInput ...
                + 0.25*ones(1,shockQuality)),'linewidth',2,'color','blue');
            h3 = plot(app.UIAxes,app.xShockInput,-tan(app.shockAngle)*(app.xShockInput ...
                + 0.25*ones(1,shockQuality)),'linewidth',2,'color','blue');
            X = app.xCoordinates(1:3); 
            Y = app.yCoordinates(1:3); 
            patch(app.UIAxes,X,Y,'black');
            app.UIAxes.XLim = [-1 1]; 
            app.UIAxes.YLim = [-1 1];
            hold(app.UIAxes,'off');
        end

        % Callback function
        function UpstreamMachNumberM1SpinnerValueChanged(app, event)
           
        end

        % Value changing function: UpstreamMachNumberM1Slider
        function UpstreamMachNumberM1SliderValueChanging(app, event)
           app.M1 = event.Value;
           app.flowDeflectionAngle = app.FlowDeflectionAngleEditField.Value*pi/180; 
           app.strongSolution = app.StrongSolutionCheckBox.Value;
           app.highRegionSilenceInfluence = app.HighlightRegionofInfluenceCheckBox.Value; 
           app.highFlowRegimes = app.HighlightFlowRegimesCheckBox_2.Value; 
           app.yCoordinates = [0 -0.5*tand(app.flowDeflectionAngle*180/pi) 0.5*tand(app.flowDeflectionAngle*180/pi) 0];
            lambda = (((app.M1)^2 - 1)^2 - 3*(1 + (app.gamma - 1)/2*(app.M1)^2)...
                *(1 + (app.gamma + 1)/2*(app.M1)^2)*(tan(app.flowDeflectionAngle))^2)^(1/2); 
            chi = (((app.M1)^2 - 1)^3 - 9*(1 + (app.gamma - 1)/2*(app.M1)^2)...
                    *(1 + (app.gamma - 1)/2*(app.M1)^2 + (app.gamma + 1)/4*(app.M1)^4)...
                    *(tan(app.flowDeflectionAngle))^2)/lambda^3;
            app.shockAngle = atan(((app.M1)^2 - 1 + 2*lambda*cos((4*pi*(app.strongSolution==0) + acos(chi))/3))...
                /(3*tan(app.flowDeflectionAngle)*(1 + (app.gamma - 1)/2*(app.M1)^2)));
            app.maxShockAngle = 1/2*acos(((app.M1)^2*(app.gamma-1)+4 ...
                - sqrt(16*(app.gamma + 1) + 8*(app.M1)^2*((app.gamma)^2 - 1) + (app.M1)^4*(app.gamma + 1)^2))...
                    /(2*app.gamma*(app.M1)^2));
            app.maxDeflectionAngle = atan((2*((app.M1)^2*(sin(app.maxShockAngle))^2 - 1))...
                                    /(tan(app.maxShockAngle)*(2 + (app.M1)^2*(app.gamma + cos(2*app.maxShockAngle)))));
             if (app.flowDeflectionAngle <= app.maxDeflectionAngle)
                cla(app.UIAxes);
                hold(app.UIAxes,'on')
                h1 = plot(app.UIAxes,app.xCoordinates,app.yCoordinates,'linewidth',2,'color','black');
                shockQuality = length(app.xShockInput);
                h2 = plot(app.UIAxes,app.xShockInput,tan(app.shockAngle)*(app.xShockInput ...
                + 0.25*ones(1,shockQuality)),'linewidth',2,'color','blue');
                h3 = plot(app.UIAxes,app.xShockInput,-tan(app.shockAngle)*(app.xShockInput ...
                + 0.25*ones(1,shockQuality)),'linewidth',2,'color','blue');
                app.UIAxes.XLim = [-1 1]; 
                app.UIAxes.YLim = [-1 1];
                X = app.xCoordinates(1:3); 
                Y = app.yCoordinates(1:3);
                if (app.HighlightFlowRegimesCheckBox_2.Value == 1)
                    if (app.StrongSolutionCheckBox.Value == 0)
                        fill(app.UIAxes,[app.xShockInput fliplr(app.xShockInput)]...
                        ,[h2.YData,fliplr(h3.YData)],'yellow','FaceAlpha',0.4);
                        text(app.UIAxes,0.50,0,'$M_{2} > 1$','Color','black',...
                        'FontWeight','bold','Interpreter','latex','FontSize',15);
                        
                    else 
                        fill(app.UIAxes,[app.xShockInput fliplr(app.xShockInput)]...
                        ,[h2.YData,fliplr(h3.YData)],'blue','FaceAlpha',0.3);
                        text(app.UIAxes,0.50,0,'$M_{2} < 1$','Color','black',...
                        'FontWeight','bold','Interpreter','latex','FontSize',15);
                    end 
                    X2 = -1:0.00042833:-0.25; 
                    Y2 = 1.5*ones(1,1751); 
                    fill(app.UIAxes,[app.xShockInput fliplr(app.xShockInput)]...
                        ,[h2.YData,fliplr(Y2)],'yellow','FaceAlpha',0.4,'LineStyle','none');
                    fill(app.UIAxes,[app.xShockInput fliplr(app.xShockInput)]...
                        ,[-Y2,fliplr(h3.YData)],'yellow','FaceAlpha',0.4,'LineStyle','none');
                    fill(app.UIAxes,[X2 fliplr(X2)]...
                        ,[-Y2 fliplr(Y2)],'yellow','FaceAlpha',0.4,'LineStyle','none');
                    text(app.UIAxes,-0.75,0,'$M_{1} > 1$','Color','black',...
                        'FontWeight','bold','Interpreter','latex','FontSize',15);
                end 
                if (app.HighlightRegionofInfluenceCheckBox.Value == 1)
                    fill(app.UIAxes,[app.xShockInput fliplr(app.xShockInput)]...
                        ,[h2.YData,fliplr(h3.YData)],'blue','FaceAlpha',0.2);
                end 
                patch(app.UIAxes,X,Y,'black');
                hold(app.UIAxes,'off');
             else 
                cla(app.UIAxes);
                degDeflectionAngle = app.maxDeflectionAngle*180/pi;
                text(app.UIAxes,-0.37,0.05,'Solution not possible','Color','red',...
                    'FontWeight','bold','FontSize',15);
                text(app.UIAxes,-0.24,-0.08,(sprintf('$\\theta_{max} = %.3f$',...
                   degDeflectionAngle)),'Color','red',...
                    'FontWeight','bold','Interpreter','latex','FontSize',15);
                app.UIAxes.XLim = [-1 1]; 
                app.UIAxes.YLim = [-1 1];
            end 
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {252, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.FlowDeflectionAngleEditFieldLabel = uilabel(app.LeftPanel);
            app.FlowDeflectionAngleEditFieldLabel.Text = 'Flow Deflection Angle';
            app.FlowDeflectionAngleEditField = uieditfield(app.LeftPanel, 'numeric');
            app.FlowDeflectionAngleEditField.Limits = [0 90];
            app.FlowDeflectionAngleEditField.Value = 20;
            app.StrongSolutionCheckBox = uicheckbox(app.LeftPanel);
            app.StrongSolutionCheckBox.Text = ' Strong Solution';
            app.OptionsLabel = uilabel(app.LeftPanel);
            app.OptionsLabel.Text = 'Options';
            app.HighlightRegionofInfluenceCheckBox = uicheckbox(app.LeftPanel);
            app.HighlightRegionofInfluenceCheckBox.Text = ' Highlight Region of Influence';
            app.HighlightFlowRegimesCheckBox_2 = uicheckbox(app.LeftPanel);
            app.HighlightFlowRegimesCheckBox_2.Text = ' Highlight Flow Regimes';
            app.ResetButton = uibutton(app.LeftPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.UpstreamMachNumberM1SliderLabel = uilabel(app.LeftPanel);
            app.UpstreamMachNumberM1SliderLabel.Text = 'Upstream Mach Number ( M1 )';
            app.UpstreamMachNumberM1Slider = uislider(app.LeftPanel);
            app.UpstreamMachNumberM1Slider.Limits = [1 10];
            app.UpstreamMachNumberM1Slider.ValueChangingFcn = createCallbackFcn(app, @UpstreamMachNumberM1SliderValueChanging, true);
            app.UpstreamMachNumberM1Slider.Value = 2;
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Oblique Shock Solutions')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ObliqueShock_Applet

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end